classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CreateTestDataSpectrogramsButton  matlab.ui.control.Button
        Bar_2                           matlab.ui.control.Button
        StatusPredictLabel              matlab.ui.control.Label
        StatusPredictLED                matlab.ui.control.Lamp
        StatusTrainingLabel             matlab.ui.control.Label
        StatusTrainingLED               matlab.ui.control.Lamp
        FPREditField                    matlab.ui.control.EditField
        FPREditField_2Label             matlab.ui.control.Label
        F1EditField                     matlab.ui.control.EditField
        F1EditField_2Label              matlab.ui.control.Label
        TNREditFieldLabel_2             matlab.ui.control.Label
        TNREditField                    matlab.ui.control.EditField
        accuracyEditField               matlab.ui.control.EditField
        accuracyEditField_2Label        matlab.ui.control.Label
        TPREditField                    matlab.ui.control.EditField
        TPREditField_2Label             matlab.ui.control.Label
        FOREditField                    matlab.ui.control.EditField
        FOREditField_2Label             matlab.ui.control.Label
        NPVEditField                    matlab.ui.control.EditField
        NPVEditField_2Label             matlab.ui.control.Label
        FDREditField                    matlab.ui.control.EditField
        FDREditField_2Label             matlab.ui.control.Label
        PPVEditField                    matlab.ui.control.EditField
        PPVEditField_2Label             matlab.ui.control.Label
        StatusSpectrogramLabel          matlab.ui.control.Label
        StatusSpectrogramLED            matlab.ui.control.Lamp
        CreateSpectrogramsButton        matlab.ui.control.Button
        TrainingFilesLabel              matlab.ui.control.Label
        BrowseFileButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ObjectLabelButtonGroup          matlab.ui.container.ButtonGroup
        spectrogramBButton              matlab.ui.control.RadioButton
        spectrogramAButton              matlab.ui.control.RadioButton
        accuracyEditField_old           matlab.ui.control.EditField
        accuracyEditFieldLabel          matlab.ui.control.Label
        FPREditField_old                matlab.ui.control.EditField
        FPREditFieldLabel               matlab.ui.control.Label
        F1EditField_old                 matlab.ui.control.EditField
        F1EditFieldLabel                matlab.ui.control.Label
        TNREditField_old                matlab.ui.control.EditField
        TNREditFieldLabel               matlab.ui.control.Label
        TPREditField_old                matlab.ui.control.EditField
        TPREditFieldLabel               matlab.ui.control.Label
        FOREditField_old                matlab.ui.control.EditField
        FOREditFieldLabel               matlab.ui.control.Label
        NPVEditField_old                matlab.ui.control.EditField
        NPVEditFieldLabel               matlab.ui.control.Label
        FDREditField_old                matlab.ui.control.EditField
        FDREditFieldLabel               matlab.ui.control.Label
        PPVEditField_old                matlab.ui.control.EditField
        PPVEditFieldLabel               matlab.ui.control.Label
        LoadvalidationdataButton        matlab.ui.control.Button
        ValidateButton                  matlab.ui.control.Button
        DatafiletovalidateEditField     matlab.ui.control.EditField
        DatafiletovalidateLabel         matlab.ui.control.Label
        PredictButton                   matlab.ui.control.Button
        PredictasEditField              matlab.ui.control.EditField
        PredictasEditFieldLabel         matlab.ui.control.Label
        LabelofValidationDataEditField  matlab.ui.control.EditField
        LabelofValidationDataEditFieldLabel  matlab.ui.control.Label
        CurrentnetEditField             matlab.ui.control.EditField
        CurrentnetEditFieldLabel        matlab.ui.control.Label
        LoadTrainedNetworkButton        matlab.ui.control.Button
        TrainedNetworkNameEditField     matlab.ui.control.EditField
        TrainedNetworkNameEditFieldLabel  matlab.ui.control.Label
        NetNameEditField                matlab.ui.control.EditField
        NetNameEditFieldLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Bar_4                           matlab.ui.control.Button
        Bar_1                           matlab.ui.control.Button
        Bar_3                           matlab.ui.control.Button
        Bar_6                           matlab.ui.control.Button
        Bar_5                           matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        LoadTestDataButton              matlab.ui.control.Button
        TrainFileNameEditField          matlab.ui.control.EditField
        TrainFileNameEditFieldLabel     matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        TestFileEditField               matlab.ui.control.EditField
        TestFileEditFieldLabel          matlab.ui.control.Label
        TrainButton                     matlab.ui.control.Button
        TrainingSamplesSlider           matlab.ui.control.Slider
        TrainingSamplesSliderLabel      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        layers, options;                    %network properties 
        net;                                %used network
        imds, imdsTrain, imdsValidation, imdsPredict;    %image data objects
        nameOfCurrentTest;
        testImagecurrentIndex = 1;
        YPred;
        currentFolder;
    end
    
    methods (Access = private)
%==============================================================================================================================================           
        function updateDataset(app)
            app.currentFolder = pwd;                       
            app.imds = imageDatastore(fullfile(app.currentFolder, 'trainingData'), 'IncludeSubfolders', true, 'LabelSource', 'foldernames');
            %% Getting number of labels
            filesToTrain = int16(app.TrainingSamplesSlider.Value);
            [app.imdsTrain,app.imdsValidation] = splitEachLabel(app.imds,filesToTrain,'randomize');
        end
%==============================================================================================================================================        
        function networkInit(app)
            
            % count number of subfolder in trainingData
            app.currentFolder = pwd;
            trainingFolder = dir(app.currentFolder + "\trainingData");
            num_layer = length(trainingFolder)-2;
            
            % Initialize the network with fixed value before training
            app.layers = [
                imageInputLayer(size(readimage(app.imds,1)))
                
                convolution2dLayer(64,8,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(32,'Stride',8)
                
                convolution2dLayer(32,16,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(16,'Stride',16)
                
                convolution2dLayer(16,32,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                fullyConnectedLayer(num_layer)
                softmaxLayer
                classificationLayer];
            
            %% Specify Training Options for the model
            app.options = trainingOptions('sgdm', ...
                'InitialLearnRate',0.01, ...
                'MaxEpochs',5, ...
                'Shuffle','every-epoch', ...
                'ValidationData',app.imdsValidation, ...
                'ValidationFrequency',30, ...
                'Verbose',false, ...
                'Plots','training-progress', ...
                'MiniBatchSize',2, ...
                'ExecutionEnvironment','gpu');
        end
%==============================================================================================================================================           
        function updateOutput(app)
            %Update the output display image in predicting mode
            app.PredictasEditField.Value = string(app.YPred(app.testImagecurrentIndex,1));
            dataFile = app.TestFileEditField.Value;
            app.currentFolder = pwd;   
            a = app.currentFolder + "/testingData/" + dataFile + "/" + dataFile +"_"+ string(app.testImagecurrentIndex) +".jpg";           
            app.Image.ImageSource = a;
        end
%==============================================================================================================================================   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            if(app.NetNameEditField.Value=="")
                set(app.StatusTrainingLED, 'visible', 'off');
                set(app.StatusTrainingLabel, 'visible', 'off');
                errordlg("Please insert the test name to save the network", "lack test Name")
                return;
            else
                % Change the status bar to processing
                set(app.StatusTrainingLED, 'visible', 'on');
                app.StatusTrainingLED.Color = [0.93, 0.69, 0.13];
                set(app.StatusTrainingLabel, 'visible', 'on');
                set(app.StatusTrainingLabel, 'Text', 'Processing');
     
                %pause(2);
                
                updateDataset(app);
                networkInit(app);
                app.net = trainNetwork(app.imdsTrain, app.layers, app.options);
                app.nameOfCurrentTest=app.NetNameEditField.Value;
                app.CurrentnetEditField.Value = app.nameOfCurrentTest;
                net = app.net;
                save([app.nameOfCurrentTest,'.mat'],'net');
                
                % Change the status bar to finish
                set(app.StatusTrainingLabel, 'Text', 'Finished');
                app.StatusTrainingLED.Color = [0.00, 1.00, 0.00];
                
                
                % HERE 
%                 app.imdsValidation = imageDatastore(fullfile(app.currentFolder, 'validationData'),...
%             'IncludeSubfolders',true,'LabelSource','foldernames');
%                 try
%                     YPred = classify(app.net,app.imdsValidation);
%                 catch
%                     msgbox("network not loaded or trained, please run training or load a trained network",'modal');
%                     return;
%                 end
%                 YValidation = app.imdsValidation.Labels;
%                 app.accuracyEditField_old.Value = string(sum(YPred == YValidation)/numel(YValidation));
%                 
%                 plotconfusion(YPred,YValidation)
%                 
%                 TP = 0;
%                 TN = 0;
%                 FP = 0;
%                 FN = 0;
%                 
%                 for i=1:size(YPred)
%                     if (YValidation(i)=="spectrogramA")&&(YPred(i)=="spectrogramA")
%                         TP = TP+1;
%                     end
%                     if (YValidation(i)=="spectrogramB")&&(YPred(i)=="spectrogramB")
%                         TN = TN+1;
%                     end
%                     if (YValidation(i)=="spectrogramB")&&(YPred(i)=="spectrogramA")
%                         FP = FP+1;
%                     end
%                     if (YValidation(i)=="spectrogramA")&&(YPred(i)=="spectrogramB")
%                         FN = FN+1;
%                     end
%                 end
%                 app.PPVEditField_old.Value = string(TP/(FP+TP));
%                 app.FDREditField_old.Value = string(FP/(FP+TP));
%                 app.NPVEditField_old.Value = string(TN/(TN+FN));
%                 app.FOREditField_old.Value = string(FN/(TN+FN));
%                 app.TNREditField_old.Value = string(TN/(TN+FP));
%                 app.F1EditField_old.Value = string(2*TP/(2*TP+FP+FN));
%                 TPR = TP/(TP+FN);
%                 app.TPREditField_old.Value = string(TPR);            
%                 FPR = FP/(FP+TN);
%                 app.FPREditField_old.Value = string(FPR);
%                 
%                 FPRm = [0 FPR 1];
%                 TPRm = [0 TPR 1];
%                 plot(app.UIAxes,FPRm, TPRm)
%                 xlabel(app.UIAxes,'spectrogramA');
%                 ylabel(app.UIAxes,'spectrogramB');
%                 grid(app.UIAxes,"on");
%                 axis(app.UIAxes,[0 1 0 1]);
%                 app.UIAxes.Visible = 'on';
            end
            
            
        end

        % Button pushed function: LoadButton
        function LoadTrainDataButton_2Pushed(app, event)
%             
%             %-- Retrieve object label from input field
%             dataLabel = app.ObjectLabelButtonGroup.SelectedObject.Text;
%             disp(dataLabel);
%             if(dataLabel == "")
%                 errordlg("dataLabel not inserted");
%                 return;
%             end
%             %-- Retrieve training data file name in the same directory
%             dataFile = app.TrainFileNameEditField.Value;
%             if(dataFile == "")
%                 errordlg("File of Data is not inserted");
%                 return;
%             end
%             msgbox("reading Data from xlsx file","modal");
%             B = readtable(dataFile+".xlsx");
%             msgbox("Performing Gabor Transformation to read data to pictures","modal");
%             t = (1:width(B)); % Time vector 1:3400 samples
%             mkdir("trainingData");
%             mkdir("trainingData/"+dataLabel);
%             for c = 1:200
%                 close all
%                 S = table2array(B(c,1:width(B))); 
%                 S = S./max(max(S),abs(min(S))); % normalizing
%                 L = 10; %Signal duration = 10s assumption
%                 n = 3400; % Number of samples
%                 k = (2*pi/L)*[0:n/2-1 -n/2:-1]; ks = fftshift(k); 
%                 Sgt_spec = [];
%                 tslide = 0:20:3400; % Moving Gabor filter every 20 samples !
%                 for j = 1:length(tslide)
%                     g = exp(-5*1e-5*(t-tslide(j)).^2); %Gabor filter function
%                     Sg = g.*S;
%                     Sgt = fft(Sg);
%                     Sgt_spec=[Sgt_spec; abs(fftshift(Sgt))];
%                 end
%                 Sgt_spec=Sgt_spec';% transpose the Spectrogram for better representation
%                 figure('Name',"Spectrogram of reading "+c+" of Object B");
%                 pcolor(tslide,ks,Sgt_spec);
%                 shading(gca, 'interp');
%                 set(gca,'Ylim',[30 60]);
%                 colormap(gca,'gray');
%                 set(gca, 'Visible', 'off');
%                 exportgraphics(gca,"trainingData/"+dataLabel+"/"+dataLabel+"_"+c+".jpg",'Resolution',100)
%             end
%             msgbox("Pictures of data created in trainingData/")
        end

        % Button pushed function: LoadTestDataButton
        function LoadTestDataButtonPushed(app, event)

            % Browse files
            [filename, path] = uigetfile('*.*', 'Select One Files', 'MultiSelect', 'off');
                        
            % Check if cancel button is pressed
            if ~isnumeric(filename) 
 
                filename = extractBefore(filename, ".");
                set(app.TestFileEditField, 'Value', filename);
                
                % Enable Button Create Spectrogram
                set(app.CreateTestDataSpectrogramsButton, 'enable', 'on');
                
                set(app.StatusPredictLED, 'visible', 'off');
                set(app.StatusPredictLabel, 'visible', 'off');
                set(app.PredictButton, 'enable', 'off');
                set(app.NextButton, 'enable', 'off');
                set(app.PreviousButton, 'enable', 'off');
                set(app.PredictasEditField, 'visible', 'off');
                set(app.PredictasEditFieldLabel, 'visible', 'off');
          
            else             
                return;
                
            end            
        end

        % Button pushed function: LoadTrainedNetworkButton
        function LoadTrainedNetworkButtonPushed(app, event)

            try  
                % Browse files
                [filename, path] = uigetfile({'*.*'; '*.mat'}, 'Select One Files', 'MultiSelect', 'off');
                                            
                % Check if cancel button is pressed
                if ~isnumeric(filename) 

                    filename = extractBefore(filename, ".");
                    
                    app.TrainedNetworkNameEditField.Value = filename;
                    
                    %loading the saved network
                    load(app.TrainedNetworkNameEditField.Value,"-mat",'net');
                    app.net = net;
                    %disp(app.net);
                    
                    if(isempty(app.net))
                        errordlg('test network could not be loaded');
                        return;
                    end
                    msgbox("Test is successfully loaded");
                    
                    set(app.PredictasEditField, 'visible', 'off');
                    set(app.PredictasEditFieldLabel, 'visible', 'off');
                    set(app.StatusPredictLED, 'visible', 'off');
                    set(app.StatusPredictLabel, 'visible', 'off');
                    app.NextButton.Enable = 'off';
                    app.PreviousButton.Enable = 'off';
                            
                else             
                    return;
                end  
                
            catch
                errordlg("Test cannot be found","Load failed","modal");
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            trained = app.TrainedNetworkNameEditField.Value;
            
            if(trained == "")
                errordlg("File of Trained Network is not inserted");
                return;
            else
                
                % Change the status bar to processing
                set(app.StatusPredictLED, 'visible', 'on');
                app.StatusPredictLED.Color = [0.93, 0.69, 0.13];
                set(app.StatusPredictLabel, 'visible', 'on');
                set(app.StatusPredictLabel, 'Text', 'Processing');
                set(app.CreateTestDataSpectrogramsButton, 'enable', 'off');
                set(app.PredictasEditField, 'visible', 'off');
                set(app.PredictasEditFieldLabel, 'visible', 'off');
                app.NextButton.Enable = 'off';
                app.PreviousButton.Enable = 'off';
            
                %pause(2);
                
                dataFile = app.TestFileEditField.Value;    
                
                %fullfile(fullfile(app.currentFolder, 'testingData'), dataFile)
                
                app.imdsPredict = imageDatastore(fullfile(fullfile(app.currentFolder, 'testingData'), dataFile));
                %disp(app.imdsPredict);
                
                if(isempty(app.net))
                    errordlg("network not loaded or no trained network found");
                    return;
                end
                if(isempty(app.imdsPredict))
                    errordlg("Data to predict not loaded into the program");
                    return;
                end
                try
                app.YPred = classify(app.net,app.imdsPredict);
                catch
                    msgbox("cannot execute predict due to difference between network and image resolution");
                    return;
                end
                updateOutput(app);
                
                set(app.PredictasEditField, 'visible', 'on');
                set(app.PredictasEditFieldLabel, 'visible', 'on');
                
                 % Change the status bar to finish
                set(app.StatusPredictLabel, 'Text', 'Finished');
                app.StatusPredictLED.Color = [0.00, 1.00, 0.00];
                set(app.CreateTestDataSpectrogramsButton, 'enable', 'on');
    
                app.NextButton.Enable = 'on';
                app.PreviousButton.Enable = 'on';
            end       
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if(app.testImagecurrentIndex==height(app.YPred))
                msgbox("end of testing file","modal");
                return
            end
            app.testImagecurrentIndex = app.testImagecurrentIndex+1;
            updateOutput(app);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if(app.testImagecurrentIndex==1)
                msgbox("this is the first file in the testing set","modal");
                return
            end
            app.testImagecurrentIndex = app.testImagecurrentIndex-1;
            updateOutput(app);
        end

        % Button pushed function: LoadvalidationdataButton
        function LoadvalidationdataButtonPushed(app, event)
%             mkdir("validationData");
%             app.currentFolder = pwd;
%             %========================================================================================================================
%             dataFile = app.DatafiletovalidateEditField.Value;
%             if(dataFile == "")
%                 errordlg("File of Data is not inserted");
%                 return;
%             end
%             dataLabel = app.LabelofValidationDataEditField.Value;
%             if(dataLabel == "")
%                 errordlg("Label of Data is not inserted");
%                 return;
%             end
%             %========================================================================================================================
%             mkdir("validationData/"+dataLabel);
%             msgbox("reading Data from xlsx file","modal");
%             try
%                 B = readtable(dataFile+".xlsx");
%             catch
%                 msgbox(" program cannot find the specified file","modal");
%                 return;
%             end
%             msgbox("Performing Gabor Transformation to read data to pictures","modal");
%             t = (1:width(B)); % Time vector 1:3400 samples
%             for c = 1:height(B)
%                 close all
%                 S = table2array(B(c,1:width(B))); 
%                 S = S./max(max(S),abs(min(S))); % normalizing
%                 L = 10; %Signal duration = 10s assumption
%                 n = 3400; % Number of samples
%                 k = (2*pi/L)*[0:n/2-1 -n/2:-1]; ks = fftshift(k); 
%                 Sgt_spec = [];
%                 tslide = 0:20:3400; % Moving Gabor filter every 20 samples !
%                 for j = 1:length(tslide)
%                     g = exp(-5*1e-5*(t-tslide(j)).^2); %Gabor filter function
%                     Sg = g.*S;
%                     Sgt = fft(Sg);
%                     Sgt_spec=[Sgt_spec; abs(fftshift(Sgt))];
%                 end
%                 Sgt_spec=Sgt_spec';% transpose the Spectrogram for better representation
%                 pcolor(tslide,ks,Sgt_spec);
%                 shading(gca, 'interp');
%                 set(gca,'Ylim',[30 60]);
%                 colormap(gca,'gray');
%                 set(gca, 'Visible', 'off');
%                 exportgraphics(gca,"validationData/"+dataLabel+"/test_"+c+".jpg",'Resolution',100)
%                 pause(0.01);
%             end
%             msgbox("Pictures of data created in validationData/");
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
%             app.imdsValidation = imageDatastore(fullfile(app.currentFolder, 'validationData'),...
%             'IncludeSubfolders',true,'LabelSource','foldernames');
%             try
%                 YPred = classify(app.net,app.imdsValidation);
%             catch
%                 msgbox("network not loaded or trained, please run training or load a trained network",'modal');
%                 return;
%             end
%             YValidation = app.imdsValidation.Labels;
%             app.accuracyEditField_old.Value = string(sum(YPred == YValidation)/numel(YValidation));
%             
%             plotconfusion(YPred,YValidation)
%             
%             TP = 0;
%             TN = 0;
%             FP = 0;
%             FN = 0;
%             
%             for i=1:size(YPred)
%                 if (YValidation(i)=="spectrogramA")&&(YPred(i)=="spectrogramA")
%                     TP = TP+1;
%                 end
%                 if (YValidation(i)=="spectrogramB")&&(YPred(i)=="spectrogramB")
%                     TN = TN+1;
%                 end
%                 if (YValidation(i)=="spectrogramB")&&(YPred(i)=="spectrogramA")
%                     FP = FP+1;
%                 end
%                 if (YValidation(i)=="spectrogramA")&&(YPred(i)=="spectrogramB")
%                     FN = FN+1;
%                 end
%             end
%             app.PPVEditField_old.Value = string(TP/(FP+TP));
%             app.FDREditField_old.Value = string(FP/(FP+TP));
%             app.NPVEditField_old.Value = string(TN/(TN+FN));
%             app.FOREditField_old.Value = string(FN/(TN+FN));
%             app.TNREditField_old.Value = string(TN/(TN+FP));
%             app.F1EditField_old.Value = string(2*TP/(2*TP+FP+FN));
%             TPR = TP/(TP+FN);
%             app.TPREditField_old.Value = string(TPR);            
%             FPR = FP/(FP+TN);
%             app.FPREditField_old.Value = string(FPR);
%             
%             FPRm = [0 FPR 1];
%             TPRm = [0 TPR 1];
%             plot(app.UIAxes,FPRm, TPRm)
%             xlabel(app.UIAxes,'spectrogramA');
%             ylabel(app.UIAxes,'spectrogramB');
%             grid(app.UIAxes,"on");
%             axis(app.UIAxes,[0 1 0 1]);
%             app.UIAxes.Visible = 'on';
        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)

            % Browse files
            [filename, path] = uigetfile('*.*', 'Select One or More Files', 'MultiSelect', 'on');
                        
            % Check if cancel button is pressed
            if ~isnumeric(filename) 
%                 set(app.CreateSpectrogramsButton, 'enable', 'off');
%                 set(app.StatusSpectrogramLED, 'visible', 'off');
%                 set(app.StatusSpectrogramLabel, 'visible', 'off');
%                 set(app.TrainButton, 'enable', 'off');
%                 set(app.StatusTrainingLED, 'visible', 'off');
%                 set(app.StatusTrainingLabel, 'visible', 'off');
%                 set(app.NetNameEditField, 'enable', 'off');   
                 
                % Check if file is char, if yes -> convert to cell
                if ischar(filename)
                    filename = cellstr(filename);     
                end
                
                % Get file name without .xlsx
                for i=1:length(filename)         
                    filename(i) = extractBefore(filename(i), ".");
                end 
                
                % Add data into table
                set(app.UITable, 'Data', transpose(filename));
                
                % Enable Button Create Spectrogram
                set(app.CreateSpectrogramsButton, 'enable', 'on');
                
                set(app.StatusSpectrogramLED, 'visible', 'off');
                set(app.StatusSpectrogramLabel, 'visible', 'off');
                set(app.TrainButton, 'enable', 'off');
                set(app.StatusTrainingLED, 'visible', 'off');
                set(app.StatusTrainingLabel, 'visible', 'off');
                set(app.NetNameEditField, 'enable', 'off');   
                
            else          
                return;
            end            

        end

        % Button pushed function: CreateSpectrogramsButton
        function CreateSpectrogramsButtonPushed(app, event)
            
            % Change the status bar to processing
            set(app.StatusSpectrogramLED, 'visible', 'on');
            app.StatusSpectrogramLED.Color = [0.93, 0.69, 0.13];
            set(app.StatusSpectrogramLabel, 'visible', 'on');
            set(app.StatusSpectrogramLabel, 'Text', 'Processing');
            set(app.CreateSpectrogramsButton, 'enable', 'off');
         

            % Run function Spectrogram here
            filename = get(app.UITable, 'Data');

            if(filename == "")
                errordlg("File of Data is not inserted");
            return;
            
            else           
                pause(2);
%                 mkdir("trainingData");
%                 app.currentFolder = pwd;
%                 app.testImagecurrentIndex = 1;
%                        
%                 for i=1:length(filename)   
%                     try 
%                         table = readtable("dataset/" + filename(i) + ".xlsx");
%                         data_range = table(:,7:end); % rows 1 to the end and columns 7th to the end              
%                     catch
%                         set(app.StatusSpectrogramLED, 'visible', 'off');
%                         set(app.StatusSpectrogramLabel, 'visible', 'off');
%                         set(app.TrainButton, 'enable', 'off');
%                         set(app.StatusTrainingLED, 'visible', 'off');
%                         set(app.StatusTrainingLabel, 'visible', 'off');
%                         set(app.NetNameEditField, 'enable', 'off');  
%                         msgbox("File not found, please insert another name");
%                         return;
%                     end
%                     
%                     dataLabel = filename(i);
%                     
%                     msgbox("Performing Gabor Transformation to read data to pictures", "modal");
%                     n = width(data_range); % Number of samples
%                     t = (1:n); % Time vector 1:3400 samples
%                     mkdir("trainingData/" + dataLabel);
%                               
%                     for c = 1:height(data_range)
%                         close all
%                         S = table2array(data_range(c,1:n)); 
%                         S = S./max(max(S),abs(min(S))); % normalizing
%                         L = 10; %Signal duration = 10s assumption
%     
%                         k = (2*pi/L)*[0:n/2-1 -n/2:-1]; ks = fftshift(k); 
%                         Sgt_spec = [];
%                         tslide = 0:20:n; % Moving Gabor filter every 20 samples !
%                         for j = 1:length(tslide)
%                             g = exp(-5*1e-5*(t-tslide(j)).^2); %Gabor filter function
%                             Sg = g.*S;
%                             Sgt = fft(Sg);
%                             Sgt_spec=[Sgt_spec; abs(fftshift(Sgt))];
%                             
%                         end
%                         Sgt_spec=Sgt_spec';% transpose the Spectrogram for better representation
%                         figure('Name', "Spectrogram of reading " + c + " of " + dataLabel);
%                         pcolor(tslide,ks,Sgt_spec);
%                         shading(gca, 'interp');
%                         set(gca,'Ylim',[30 60]);
%                         colormap(gca, 'gray');
%                         set(gca, 'Visible', 'off');
%                         exportgraphics(gca,"trainingData/" +  dataLabel + "/" + dataLabel + "_" + c + ".jpg", 'Resolution', 100)
%                     end                         
%                end 
                
                msgbox("Pictures of data created in trainingData/");
    
                % Change the status bar to finish
                set(app.StatusSpectrogramLabel, 'Text', 'Finished');
                app.StatusSpectrogramLED.Color = [0.00, 1.00, 0.00];
                set(app.CreateSpectrogramsButton, 'enable', 'on');
     
                % Enable Training Button
                set(app.TrainButton, 'enable', 'on');   
    
                % Enable NetNameEditField Train
                set(app.NetNameEditField, 'enable', 'on');   
            end 
        end

        % Button pushed function: CreateTestDataSpectrogramsButton
        function CreateTestDataSpectrogramsButtonPushed(app, event)

            set(app.PredictButton, 'enable', 'off');  
            set(app.PredictasEditField, 'visible', 'off');
            set(app.PredictasEditFieldLabel, 'visible', 'off');
            set(app.StatusPredictLED, 'visible', 'off');
            set(app.StatusPredictLabel, 'visible', 'off');
            app.NextButton.Enable = 'off';
            app.PreviousButton.Enable = 'off';
            
            dataFile = app.TestFileEditField.Value;
            
            if(dataFile == "")
                errordlg("File of Test is not inserted");
                return;
                
            else
                pause(2);
%                 mkdir("testingData");
%                 app.currentFolder = pwd;
%                 app.testImagecurrentIndex = 1;
%                 
%                 try
%                     data = readtable("dataset/" + dataFile + ".xlsx");
%                     data_range = data(:,7:end); % rows 1 to the end and columns 7th to the end  
%                 catch
%                     msgbox("testing File not found, please insert another name");
%                     return;
%                 end
%                 msgbox("Performing Gabor Transformation to read data to pictures","modal");               
%                 mkdir("testingData/" + dataFile);                
%                 n = width(data_range); % Number of samples
%                 t = (1:n); % Time vector 1:3400 samples
%                 for c = 1:height(data_range)
%                     close all
%                     S = table2array(data_range(c,1:width(data_range))); 
%                     S = S./max(max(S),abs(min(S))); % normalizing
%                     L = 10; %Signal duration = 10s assumption
%                     k = (2*pi/L)*[0:n/2-1 -n/2:-1]; ks = fftshift(k); 
%                     Sgt_spec = [];
%                     tslide = 0:20:n; % Moving Gabor filter every 20 samples !
%                     for j = 1:length(tslide)
%                         g = exp(-5*1e-5*(t-tslide(j)).^2); %Gabor filter function
%                         Sg = g.*S;
%                         Sgt = fft(Sg);
%                         Sgt_spec=[Sgt_spec; abs(fftshift(Sgt))];
%                     end
%                     Sgt_spec=Sgt_spec'; % transpose the Spectrogram for better representation
%                     pcolor(tslide,ks,Sgt_spec);
%                     shading(gca, 'interp');
%                     set(gca,'Ylim',[30 60]);
%                     colormap(gca,'gray');
%                     set(gca, 'Visible', 'off');                          
%                     exportgraphics(gca,"testingData/" +  dataFile + "/" + dataFile + "_" + c + ".jpg", 'Resolution', 100)                                       
%                     pause(0.01);                 
%                 end
                
                msgbox("Pictures of data created in testingData/");

                % Enable Predict Button
                set(app.PredictButton, 'enable', 'on');  
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Receiver operating characteristic')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.TrainingSamplesSliderLabel = uilabel(app.UIFigure);
            app.TrainingSamplesSliderLabel.Text = 'Training Samples';
            app.TrainingSamplesSlider = uislider(app.UIFigure);
            app.TrainingSamplesSlider.Limits = [0 200];
            app.TrainingSamplesSlider.Tooltip = {'Specify the number of picture in every dataset folder used for training'};
            app.TrainingSamplesSlider.Value = 1;
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Tooltip = {'Train a net to classify the specified classes in TrainingData'};
            app.TrainButton.Text = 'Train';
            app.TestFileEditFieldLabel = uilabel(app.UIFigure);
            app.TestFileEditFieldLabel.Text = 'Test File';
            app.TestFileEditField = uieditfield(app.UIFigure, 'text');
            app.TestFileEditField.Tooltip = {'Isert the name of the test xlsx file without the file type e.g. combinedataforA'};
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainDataButton_2Pushed, true);
            app.LoadButton.Tooltip = {'Load Data from xlxs dataset to spectrogram with Gabor transformation'};
            app.LoadButton.Text = 'Load';
            app.TrainFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.TrainFileNameEditFieldLabel.Text = 'Train File Name';
            app.TrainFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.TrainFileNameEditField.Tooltip = {'Isert the name of the xlsx file without the file type e.g. combinedataforA'};
            app.LoadTestDataButton = uibutton(app.UIFigure, 'push');
            app.LoadTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestDataButtonPushed, true);
            app.LoadTestDataButton.Tooltip = {'Load Data from xlxs dataset to spectrogram with Gabor transformation'};
            app.LoadTestDataButton.Text = 'Load Test Data';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Enable = 'off';
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.Bar_5 = uibutton(app.UIFigure, 'push');
            app.Bar_6 = uibutton(app.UIFigure, 'push');
            app.Bar_6.Text = 'Button2';
            app.Bar_3 = uibutton(app.UIFigure, 'push');
            app.Bar_3.Text = '';
            app.Bar_1 = uibutton(app.UIFigure, 'push');
            app.Bar_1.Text = '';
            app.Bar_4 = uibutton(app.UIFigure, 'push');
            app.Image = uiimage(app.UIFigure);
            app.NetNameEditFieldLabel = uilabel(app.UIFigure);
            app.NetNameEditFieldLabel.Text = 'Net Name';
            app.NetNameEditField = uieditfield(app.UIFigure, 'text');
            app.NetNameEditField.Enable = 'off';
            app.NetNameEditField.Tooltip = {'Name of Test - name of the networks to be saved in a .mat file'};
            app.TrainedNetworkNameEditFieldLabel = uilabel(app.UIFigure);
            app.TrainedNetworkNameEditFieldLabel.Text = 'Trained Network Name';
            app.TrainedNetworkNameEditField = uieditfield(app.UIFigure, 'text');
            app.LoadTrainedNetworkButton = uibutton(app.UIFigure, 'push');
            app.LoadTrainedNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainedNetworkButtonPushed, true);
            app.LoadTrainedNetworkButton.Text = 'Load Trained Network';
            app.CurrentnetEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentnetEditFieldLabel.Text = 'Current net ';
            app.CurrentnetEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentnetEditField.Editable = 'off';
            app.LabelofValidationDataEditFieldLabel = uilabel(app.UIFigure);
            app.LabelofValidationDataEditFieldLabel.Text = 'Label of Validation Data';
            app.LabelofValidationDataEditField = uieditfield(app.UIFigure, 'text');
            app.PredictasEditFieldLabel = uilabel(app.UIFigure);
            app.PredictasEditFieldLabel.Visible = 'off';
            app.PredictasEditFieldLabel.Text = 'Predict as:';
            app.PredictasEditField = uieditfield(app.UIFigure, 'text');
            app.PredictasEditField.Editable = 'off';
            app.PredictasEditField.Visible = 'off';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = 'Predict';
            app.DatafiletovalidateLabel = uilabel(app.UIFigure);
            app.DatafiletovalidateLabel.Text = 'Data file to validate';
            app.DatafiletovalidateEditField = uieditfield(app.UIFigure, 'text');
            app.ValidateButton = uibutton(app.UIFigure, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Tooltip = {'Validate the network using validation data from validationData/ and the network.'; 'If there are already image, no need to load data'; ''};
            app.ValidateButton.Text = 'Validate';
            app.LoadvalidationdataButton = uibutton(app.UIFigure, 'push');
            app.LoadvalidationdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvalidationdataButtonPushed, true);
            app.LoadvalidationdataButton.Tooltip = {'Load the data from xlsx file to images in validationData/ for validating the network'};
            app.LoadvalidationdataButton.Text = 'Load validation data';
            app.PPVEditFieldLabel = uilabel(app.UIFigure);
            app.PPVEditFieldLabel.Text = 'PPV';
            app.PPVEditField_old = uieditfield(app.UIFigure, 'text');
            app.PPVEditField_old.Editable = 'off';
            app.FDREditFieldLabel = uilabel(app.UIFigure);
            app.FDREditFieldLabel.Text = 'FDR';
            app.FDREditField_old = uieditfield(app.UIFigure, 'text');
            app.FDREditField_old.Editable = 'off';
            app.NPVEditFieldLabel = uilabel(app.UIFigure);
            app.NPVEditFieldLabel.Text = 'NPV';
            app.NPVEditField_old = uieditfield(app.UIFigure, 'text');
            app.NPVEditField_old.Editable = 'off';
            app.FOREditFieldLabel = uilabel(app.UIFigure);
            app.FOREditFieldLabel.Text = 'FOR';
            app.FOREditField_old = uieditfield(app.UIFigure, 'text');
            app.FOREditField_old.Editable = 'off';
            app.TPREditFieldLabel = uilabel(app.UIFigure);
            app.TPREditFieldLabel.Text = 'TPR';
            app.TPREditField_old = uieditfield(app.UIFigure, 'text');
            app.TPREditField_old.Editable = 'off';
            app.TNREditFieldLabel = uilabel(app.UIFigure);
            app.TNREditFieldLabel.Text = 'TNR';
            app.TNREditField_old = uieditfield(app.UIFigure, 'text');
            app.TNREditField_old.Editable = 'off';
            app.F1EditFieldLabel = uilabel(app.UIFigure);
            app.F1EditFieldLabel.Text = 'F1';
            app.F1EditField_old = uieditfield(app.UIFigure, 'text');
            app.F1EditField_old.Editable = 'off';
            app.FPREditFieldLabel = uilabel(app.UIFigure);
            app.FPREditFieldLabel.Text = 'FPR';
            app.FPREditField_old = uieditfield(app.UIFigure, 'text');
            app.FPREditField_old.Editable = 'off';
            app.accuracyEditFieldLabel = uilabel(app.UIFigure);
            app.accuracyEditFieldLabel.Text = 'accuracy';
            app.accuracyEditField_old = uieditfield(app.UIFigure, 'text');
            app.accuracyEditField_old.Editable = 'off';
            app.ObjectLabelButtonGroup = uibuttongroup(app.UIFigure);
            app.ObjectLabelButtonGroup.Title = 'Object Label';
            app.spectrogramAButton = uiradiobutton(app.ObjectLabelButtonGroup);
            app.spectrogramAButton.Text = 'spectrogramA';
            app.spectrogramAButton.Value = true;
            app.spectrogramBButton = uiradiobutton(app.ObjectLabelButtonGroup);
            app.spectrogramBButton.Text = 'spectrogramB';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File Name'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.BrowseFileButton = uibutton(app.UIFigure, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File';
            app.TrainingFilesLabel = uilabel(app.UIFigure);
            app.TrainingFilesLabel.Text = 'Training Files';
            app.CreateSpectrogramsButton = uibutton(app.UIFigure, 'push');
            app.CreateSpectrogramsButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSpectrogramsButtonPushed, true);
            app.CreateSpectrogramsButton.Enable = 'off';
            app.CreateSpectrogramsButton.Text = 'Create Spectrograms';
            app.StatusSpectrogramLED = uilamp(app.UIFigure);
            app.StatusSpectrogramLED.Visible = 'off';
            app.StatusSpectrogramLabel = uilabel(app.UIFigure);
            app.StatusSpectrogramLabel.Visible = 'off';
            app.StatusSpectrogramLabel.Text = 'Processing';
            app.PPVEditField_2Label = uilabel(app.UIFigure);
            app.PPVEditField_2Label.Text = 'PPV';
            app.PPVEditField = uieditfield(app.UIFigure, 'text');
            app.PPVEditField.Editable = 'off';
            app.FDREditField_2Label = uilabel(app.UIFigure);
            app.FDREditField_2Label.Text = 'FDR';
            app.FDREditField = uieditfield(app.UIFigure, 'text');
            app.FDREditField.Editable = 'off';
            app.NPVEditField_2Label = uilabel(app.UIFigure);
            app.NPVEditField_2Label.Text = 'NPV';
            app.NPVEditField = uieditfield(app.UIFigure, 'text');
            app.NPVEditField.Editable = 'off';
            app.FOREditField_2Label = uilabel(app.UIFigure);
            app.FOREditField_2Label.Text = 'FOR';
            app.FOREditField = uieditfield(app.UIFigure, 'text');
            app.FOREditField.Editable = 'off';
            app.TPREditField_2Label = uilabel(app.UIFigure);
            app.TPREditField_2Label.Text = 'TPR';
            app.TPREditField = uieditfield(app.UIFigure, 'text');
            app.TPREditField.Editable = 'off';
            app.accuracyEditField_2Label = uilabel(app.UIFigure);
            app.accuracyEditField_2Label.Text = 'accuracy';
            app.accuracyEditField = uieditfield(app.UIFigure, 'text');
            app.accuracyEditField.Editable = 'off';
            app.TNREditField = uieditfield(app.UIFigure, 'text');
            app.TNREditField.Editable = 'off';
            app.TNREditFieldLabel_2 = uilabel(app.UIFigure);
            app.TNREditFieldLabel_2.Text = 'TNR';
            app.F1EditField_2Label = uilabel(app.UIFigure);
            app.F1EditField_2Label.Text = 'F1';
            app.F1EditField = uieditfield(app.UIFigure, 'text');
            app.F1EditField.Editable = 'off';
            app.FPREditField_2Label = uilabel(app.UIFigure);
            app.FPREditField_2Label.Text = 'FPR';
            app.FPREditField = uieditfield(app.UIFigure, 'text');
            app.FPREditField.Editable = 'off';
            app.StatusTrainingLED = uilamp(app.UIFigure);
            app.StatusTrainingLED.Visible = 'off';
            app.StatusTrainingLabel = uilabel(app.UIFigure);
            app.StatusTrainingLabel.Visible = 'off';
            app.StatusTrainingLabel.Text = 'Processing';
            app.StatusPredictLED = uilamp(app.UIFigure);
            app.StatusPredictLED.Visible = 'off';
            app.StatusPredictLabel = uilabel(app.UIFigure);
            app.StatusPredictLabel.Visible = 'off';
            app.StatusPredictLabel.Text = 'Processing';
            app.Bar_2 = uibutton(app.UIFigure, 'push');
            app.Bar_2.Text = '';
            app.CreateTestDataSpectrogramsButton = uibutton(app.UIFigure, 'push');
            app.CreateTestDataSpectrogramsButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTestDataSpectrogramsButtonPushed, true);
            app.CreateTestDataSpectrogramsButton.Enable = 'off';
            app.CreateTestDataSpectrogramsButton.Text = 'Create Test Data Spectrograms';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end